classdef INTEGRAL_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Calc_Draw             matlab.ui.control.Button
        Methods               matlab.ui.container.ButtonGroup
        HinhThang             matlab.ui.control.RadioButton
        Simpson               matlab.ui.control.RadioButton
        Chinhxac              matlab.ui.control.RadioButton
        Result                matlab.ui.control.Label
        ResultLabel           matlab.ui.control.Label
        CndiaLabel            matlab.ui.control.Label
        CanA                  matlab.ui.control.NumericEditField
        NhpNLabel             matlab.ui.control.Label
        dataN                 matlab.ui.control.NumericEditField
        CntrnbLabel           matlab.ui.control.Label
        CanB                  matlab.ui.control.NumericEditField
        NhphmsEditFieldLabel  matlab.ui.control.Label
        Hamso                 matlab.ui.control.EditField
        Axes                  matlab.ui.control.UIAxes
        INTEGRALAPP           matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function y = hinhThang(app,fx,a,b,N)
                    syms x;
                    fx = str2func(['@(x)', fx]);
                    h = (b -a)/N;
                    s1 = (fx(a) + fx(b));
                    s2 = 0;
                    for i = 1:N-1
                        s2 = s2 + fx(a + i*h);
                    end
                    y = h/2*(s1 + 2*s2);

         end
        
        
         % Simpson function
        function y = tichphanSimpson(app,fx,a,b,N)
                    syms x;
                    fx = str2func(['@(x)', fx]);
                    h = (b - a)/N;
                    s1 = (fx(a) + fx(b));
                    s2 = 0; 
                    s3 = 0;
                    for i=1:2:N-1
                        s2 = s2 + fx(a + i*h);
                    end
                    
                    for i=2:2:N-1
                        s3 = s3 + fx(a + i*h);
                    end
                    y = h/3*(s1 + 4*s2 + 2*s3);
        end
              
        
       % A function to erase '.' character in fx
        function results = Change_Fx(app, fx)
               results = strrep(fx,'*','.*');
               results = strrep(results,'^','.^');
               results = strrep(results,'/','./');
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Calc_Draw
        function Calc_DrawButtonPushed(app, event)
             a = app.CanA.Value;
            b = app.CanB.Value;
            N = app.dataN.Value;   
            fx = app.Hamso.Value;
            fx_new = Change_Fx(app,fx);


           if(app.HinhThang.Value)
                HTRes = hinhThang(app,fx_new, a, b, N);
                app.Result.Text = sprintf('%.10f', HTRes);

                hamso  = str2func(['@(x)', fx_new]);
                range = linspace(a,b,100);
                Ydata = hamso(range);
                plot(app.Axes,range,Ydata,'--r');
           elseif(app.Simpson.Value)
                SSRes = tichphanSimpson(app,fx_new, a, b, N);
                app.Result.Text = sprintf('%.10f', SSRes);
                hamso  = str2func(['@(x)', fx_new]);
                range = linspace(a,b,100);
                Ydata = hamso(range);
                plot(app.Axes,range,Ydata,'.-b');
           else
                
                hamso  = str2func(['@(x)', fx_new]);
                CorrectRes = integral(hamso,a,b);
                app.Result.Text = sprintf('%.10f', CorrectRes);
                range = linspace(a,b,100);
                Ydata = hamso(range);
                plot(app.Axes,range,Ydata,'*y');

           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Calc_Draw = uibutton(app.UIFigure, 'push');
            app.Calc_Draw.ButtonPushedFcn = createCallbackFcn(app, @Calc_DrawButtonPushed, true);
            app.Calc_Draw.Text = 'Tính và vẽ đồ thị';
            app.Methods = uibuttongroup(app.UIFigure);
            app.Methods.Title = 'Chọn phương pháp tính tích phân';
            app.HinhThang = uiradiobutton(app.Methods);
            app.HinhThang.Text = 'Hình Thang';
            app.HinhThang.Value = true;
            app.Simpson = uiradiobutton(app.Methods);
            app.Simpson.Text = 'Simpson';
            app.Chinhxac = uiradiobutton(app.Methods);
            app.Chinhxac.Text = 'Chính xác';
            app.Result = uilabel(app.UIFigure);
            app.Result.Text = '0';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Kết Quả';
            app.CndiaLabel = uilabel(app.UIFigure);
            app.CndiaLabel.Text = 'Cận dưới a';
            app.CanA = uieditfield(app.UIFigure, 'numeric');
            app.NhpNLabel = uilabel(app.UIFigure);
            app.NhpNLabel.Text = 'Nhập N';
            app.dataN = uieditfield(app.UIFigure, 'numeric');
            app.dataN.Value = 10;
            app.CntrnbLabel = uilabel(app.UIFigure);
            app.CntrnbLabel.Text = 'Cận trên b';
            app.CanB = uieditfield(app.UIFigure, 'numeric');
            app.CanB.Value = 2;
            app.Axes = uiaxes(app.UIFigure);
            title(app.Axes, 'Đồ thị tích phân')
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            app.NhphmsEditFieldLabel = uilabel(app.UIFigure);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.Hamso = uieditfield(app.UIFigure, 'text');
            app.Hamso.Value = 'x^3*sin(x) + x*cos(x)';
            app.INTEGRALAPP = uilabel(app.UIFigure);
            app.INTEGRALAPP.Text = 'INTEGRAL APP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = INTEGRAL_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end